classdef deniftify < matlab.apps.AppBase
    %DENIFTIFY Conversion of NiFTI to DICOM
    %   Converts a NiFTI file to DICOM. Assumes both are output from XNAT
    %   with DICOM in a 'scans' folder and the NiFTI in
    %   'assessors/XX/RMAP1/FIT_FIC.nii.gz' with XX the folder
    %   corresponding to the Verdict version in the first drop down.
    %   
    %   David Atkinson.

    % Properties that correspond to app components
    properties (Access = public)
        DeniftifyAppUIFigure            matlab.ui.Figure
        SaveasmatButton                 matlab.ui.control.Button
        NumignoredendslicesEditField    matlab.ui.control.NumericEditField
        NumignoredendslicesEditFieldLabel  matlab.ui.control.Label
        AddRescaleSlopetoDICOMCheckBox  matlab.ui.control.CheckBox
        FoldernameendEditField          matlab.ui.control.EditField
        FoldernameendEditFieldLabel     matlab.ui.control.Label
        InvertNifTICheckBox             matlab.ui.control.CheckBox
        ShowDICOMcontoursCheckBox       matlab.ui.control.CheckBox
        SeriesNumberEditField           matlab.ui.control.NumericEditField
        SeriesNumberEditFieldLabel      matlab.ui.control.Label
        CheckFigureNumberLabel          matlab.ui.control.Label
        XNATfolderLabel                 matlab.ui.control.Label
        SeriesNameIncludesDropDown      matlab.ui.control.DropDown
        SeriesNameIncludesDropDownLabel  matlab.ui.control.Label
        VERDICTversionDropDown          matlab.ui.control.DropDown
        VERDICTversionDropDownLabel     matlab.ui.control.Label
        SelectXNATfolderButton          matlab.ui.control.Button
        SaveasDICOMButton               matlab.ui.control.Button
        PatientIDEditField              matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        PatientFamilyNameEditField      matlab.ui.control.EditField
        PatientFamilyNameEditFieldLabel  matlab.ui.control.Label
        DeniftifyLabel                  matlab.ui.control.Label
        transform4DropDown              matlab.ui.control.DropDown
        transform4DropDownLabel         matlab.ui.control.Label
        transform3DropDown              matlab.ui.control.DropDown
        transform3DropDownLabel         matlab.ui.control.Label
        transform2DropDown              matlab.ui.control.DropDown
        transform2DropDownLabel         matlab.ui.control.Label
        NifTItransform1DropDown         matlab.ui.control.DropDown
        NifTItransform1DropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        nSlices % Number of slices
        ny      % Number of rows
        vnifti  % Nifti volume
        midSlice % Description
        vdicom  % Description
        ht      % Handle to tiledlayout
        slOffset % Slice offset from edges
        hallax   % Handle to all axes
        mdicom % DICOM m structure
        ldicom % DICOM files l
        vntransformed  % Nifti volume transformed
        dinfo_read % Description
        DICOMscansfolder % Description
        niftiInput % 'vol' or 'fic'
    end
    
    methods (Access = private)
        
        function results = drawnifti(app)
            vn = app.vnifti ;
            
            vn = applyTransform(app, vn, app.NifTItransform1DropDown.Value) ;
            vn = applyTransform(app, vn, app.transform2DropDown.Value) ;
            vn = applyTransform(app, vn, app.transform3DropDown.Value) ;
            vn = applyTransform(app, vn, app.transform4DropDown.Value) ;

            ax4 = nexttile(app.ht,4) ;

            if app.InvertNifTICheckBox.Value == true
                voff = 1 ;
                vsign = -1 ;
            else
                voff = 0 ;
                vsign = 1 ;
            end

            if size(vn,4) > 1
                % Registration NIFTI
                vtypical = vn(:,:,app.midSlice,1) ;
                vupper = prctile(vtypical(:),98) ;
            else
                % fIC NIFTI
                vupper = 1;
            end
            imshow(voff + vsign*vn(:,:,1+app.slOffset,1),[0 vupper],'Parent',ax4)
            ax5 = nexttile(app.ht,5) ;
            imshow(voff + vsign*vn(:,:,app.midSlice,1),[0 vupper],'Parent',ax5)
            ax6 = nexttile(app.ht,6) ;
            imshow(voff + vsign*vn(:,:,app.nSlices-app.slOffset,1),[0 vupper],'Parent',ax6)

            app.hallax = [app.hallax ax4 ax5 ax6] ;
            linkaxes(app.hallax)

            if app.ShowDICOMcontoursCheckBox.Value == true
                hold(ax6,"on")
                imd = app.vdicom(:,:,app.nSlices-app.slOffset) ;
                imdtop = prctile(imd(:),95) ;
                imd(imd>imdtop)=imdtop ;
                [M,c]=contour(ax6,imd,2) ;
                c.Color = [1 0 0] ;
                c.LineWidth = 2;
                hold(ax6,"off")
            end

            app.vntransformed = vn ;
        end
        
        function vn = applyTransform(app, vn, action)
            switch action
                case 'None'
                case 'Reverse Slices'
                    vn = vn(:,:,end:-1:1, :) ;
                case 'Transpose'
                    vn = pagetranspose(vn) ;
                case 'Flip East-West'
                    vn = flip(vn,2) ;
                case 'Flip North-South'
                    vn = flip(vn,1) ;
                otherwise
                    warning('Unknown transform')
            end
        end
        
        function results = drawdicom(app)
            vd = app.vdicom ;
            upper = prctile(vd(:),95) ;
            imrange = [0 upper] ;

            ax1 = nexttile(app.ht,1) ;
            imshow(vd(:,:,1+app.slOffset),imrange,'Parent',ax1)
            ax2 = nexttile(app.ht,2) ;
            imshow(vd(:,:,app.midSlice),imrange,'Parent',ax2)
            ax3 = nexttile(app.ht,3) ;
            imshow(vd(:,:,app.nSlices-app.slOffset),imrange,'Parent',ax3)

            app.hallax = [ax1 ax2 ax3] ;
        end
        
        function results = reset(app)
            app.SaveasDICOMButton.Enable = "off";
            hfig = findobj("Tag","deniftify_fig") ;
            delete(hfig)
            
            app.PatientIDEditField.Value = '';
            app.SeriesNumberEditField.Value = 0 ;
            app.PatientFamilyNameEditField.Value = '' ;
            app.XNATfolderLabel.Text = '';
        end
        
        function statusokd = findDicom(app)
            % assumed filestructure is
            % XNATfolder (e.g. StudyID)
            %   scans
            %     XXX_b3000_XXX  
            %        DICOM
            %           single DICOM file
            %
            statusokd = false ;
            XNATfolder = app.XNATfolderLabel.Text ;
            scansfolder = fullfile(XNATfolder,'scans') ;
            if ~exist(scansfolder,'dir')
                warning('MATLAB:deniftify:checkscans',['Found no scans folder'] )
                return
            end

            app.DICOMscansfolder = scansfolder ;

            % Check exactly one folder with b3000, for example, in name
            nmatch = 0 ;
            str2match = app.SeriesNameIncludesDropDown.Value ;
            ddf = dir(scansfolder) ;
            for id = 1: length(ddf)
                if contains(ddf(id).name, str2match)
                    nmatch = nmatch + 1 ;
                    DICOMfolder = fullfile(ddf(id).folder,ddf(id).name ) ;
                end
            end
            if nmatch ~= 1
                warning('MATLAB:deniftify:matchDICOM',['Found ', ...
                    num2str(nmatch), 'matching DICOM folders.'] )
                return
            end

            % Check there is a DICOM folder below
            DICOMfolder = fullfile(DICOMfolder,'DICOM') ;
            if ~exist(DICOMfolder,'dir')
                warning('MATLAB:deniftify:DICOMfolder','No DICOM folder')
                return
            end

            % Check there is at least one DICOM file in this folder
            ddf = dir(DICOMfolder) ;
            ndmatch = 0 ;
            DICOMfile = {} ;
            for id = 1:length(ddf)
                if ~ddf(id).isdir
                    ffd = fullfile(ddf(id).folder,ddf(id).name) ;
                    if isdicom(ffd)
                        ndmatch = ndmatch+1 ;
                        DICOMfile{ndmatch} = ffd ;
                    end
                end
            end
            if ndmatch < 1
                warning('MATLAB:deniftify:DICOMfile','No DICOM files found')
                return
            end

            dinfo = dfparse(DICOMfile) ;
            app.dinfo_read = dinfo(1) ;

            [vb0, mb0, lb0] = d2mat(dinfo,{'slice','bv'},'bv',0,'op','fp') ;
            app.vdicom = vb0 ;
            app.mdicom = mb0 ;
            app.ldicom = lb0 ;
            
            app.nSlices = size(vb0,3) ;
            app.midSlice = floor((app.nSlices+1)/2) ;
            app.ny = size(vb0,1) ;

            dfinfo = dicominfo(dinfo(1).Filename) ;
            % PatientName can be more complex
            if isfield(dfinfo,'PatientID')
                app.PatientIDEditField.Value = dfinfo.PatientID ;
            end

            if isfield(dfinfo,'PatientName') && isfield(dfinfo.PatientName,'FamilyName') 
                app.PatientFamilyNameEditField.Value = dfinfo.PatientName.FamilyName ;
            end

            if isfield(dfinfo,'SeriesNumber')
                seriesNumber = dfinfo.SeriesNumber ;
                if seriesNumber > 200
                    newSeriesNumber = seriesNumber + 1 ;
                else
                    newSeriesNumber = seriesNumber + 40 ;
                end
            else
                newSeriesNumber = 99 ;
                app.SeriesNumberEditFieldLabel.FontColor = [1 0 0] ;
                app.SeriesNumberEditFieldLabel.FontWeight = 'bold' ;
            end
            app.SeriesNumberEditField.Value = newSeriesNumber ;
            

            hf = figure("Name",app.PatientIDEditField.Value, "Tag","deniftify_fig") ;
            app.ht = tiledlayout(hf,2,3,Padding='tight',TileSpacing='none') ;
            app.CheckFigureNumberLabel.Text = ['Check Figure number: ',num2str(hf.Number)] ;
            app.CheckFigureNumberLabel.Enable = "on" ;

            drawdicom(app)

            statusokd = true ;
        end
        
        function statusokn = findNifti(app)
            % XNATfolder structure assumed
            %   assessors
            %      f1
            %        OUTLOG
            %          *-x-Verdict_v1.output   (or other versions from
            %          dropdown)
            %        RMAPS1
            %          FIT_fIC.nii.gz
            %      f2

            statusokn = false ;
            XNATfolder = app.XNATfolderLabel.Text ;

            % check assessors folder
            assessorsfolder = fullfile(XNATfolder,'assessors') ;
            if ~exist(assessorsfolder,'dir')
                warning('MATLAB:deniftify:checkassessors',['Found no assessors folder'] )
                return
            end

            DBenable = "on" ; 

            % get Nifti file
            dnf = dir(assessorsfolder) ;
            nnmatch = 0 ;
            for id = 1:length(dnf) 
                if dnf(id).isdir 
                    dOUTLOG = fullfile(dnf(id).folder, dnf(id).name,'OUTLOG') ;
                    if exist(dOUTLOG,'dir')
                        dof = dir(dOUTLOG) ;
                        for idof=1:length(dof)
                            if ~dof(idof).isdir
                                ofn = dof(idof).name ;
                                if contains(ofn,['-x-',app.VERDICTversionDropDown.Value '.output'])
                                    nff = fullfile(dnf(id).folder, dnf(id).name, ...
                                      'RMAPS1','FIT_fIC.nii.gz') ;
                                    if exist(nff,'file')
                                        nnmatch = nnmatch + 1 ;
                                        NIFTImatch = nff ;
                                        app.niftiInput = 'fic' ;
                                    end
                                    nfregfolderstr = fullfile(dnf(id).folder, dnf(id).name, ...
                                        'ACQ1','*.nii.gz') ;
                                    nfregdir = dir(nfregfolderstr);
                                    if ~isempty(nfregdir)
                                        nfregffn = fullfile(nfregdir.folder, nfregdir.name) ;
                                        nnmatch = nnmatch + 1 ;
                                        NIFTImatch = nfregffn ;
                                        app.InvertNifTICheckBox.Value = 0 ;
                                        app.niftiInput = 'vol' ;
                                        DBenable = "off" ;
                                    end
                                end
                            end
                        end
                    end
                end
            end

            if nnmatch ~= 1
                warning('MATLAB:deniftify:locateNifti',['Need exactly 1 matching Nifti'] )
                return
            end

            vn = niftiread(NIFTImatch) ;
            if size(vn,3) ~= app.nSlices
                warning('MATLAB:deniftify:slices', 'nslices in DICOM and NIFTI not equal') 
                return
            end
            if size(vn,1) ~= app.ny
                warning('MATLAB:deniftify:ny','ny in DICOM and NIFTI not same')
                return
            end

            app.vnifti = vn ;

            drawnifti(app) 

            app.SaveasDICOMButton.Enable = DBenable ;

            statusokn = true ;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set the transformation drop downs
            nTransforms = {'None','Transpose','Reverse Slices','Flip East-West', ...
                'Flip North-South'} ;
            app.NifTItransform1DropDown.Items  = nTransforms ;
            app.transform2DropDown.Items = nTransforms ;
            app.transform3DropDown.Items = nTransforms ;
            app.transform4DropDown.Items = nTransforms ;

            app.slOffset = app.NumignoredendslicesEditField.Value ;
        end

        % Callback function: InvertNifTICheckBox, NifTItransform1DropDown, 
        % ...and 5 other components
        function DropDownValueChanged(app, event)
            value = app.NifTItransform1DropDown.Value;
            drawnifti(app)
        end

        % Button pushed function: SaveasDICOMButton
        function SaveasDICOMButtonPushed(app, event)
            % Using one original DICOM file to fill header for all slices
            % hence repeat that filename here
            if length(app.dinfo_read) == 1
                filesstruc(app.nSlices).Filename = app.dinfo_read.Filename; 
                [filesstruc.Filename] = deal(app.dinfo_read.Filename) ;
                locs = ones([app.nSlices 1]) ;
            else
                warning('MATLAB:deniftify:dinfo','dinfo not one')
                return
            end
            
            disp('Starting DICOM writing...')
            
            seriesNumber = app.SeriesNumberEditField.Value ;
            ficfolder = fullfile(app.DICOMscansfolder, ...
                [num2str(seriesNumber) , ...
                app.FoldernameendEditField.Value], 'DICOM') ;

            
            [status, messg, messgid] = mkdir(ficfolder) ;
            if status==0
                warning(messgid, messg)
            end

            if app.AddRescaleSlopetoDICOMCheckBox.Value
                dtype = 'fICWithRescale' ;
            else
                dtype = 'fICx1000' ;
            end

            writeDicom(app.vntransformed, dtype, 'geom', app.mdicom.geom, ...
                'fnstem','fIC', ...
                'folder_name', ficfolder , ...
                'FrameOfReferenceUID', 'keep', ...
                'SeriesInstanceUID','new', ...
                'header', {filesstruc, locs}, ...
                'ProtocolName','fIC from NIfTI', ...
                'ImageComments',['Created by deniftify using version ', ...
                   app.VERDICTversionDropDown.Value,' and transforms: ', ...
                   app.NifTItransform1DropDown.Value,'/', ...
                   app.transform2DropDown.Value,'/', ...
                   app.transform3DropDown.Value,'/', ...
                   app.transform4DropDown.Value], ...
                'SeriesNumber', seriesNumber ) ;

        end

        % Button pushed function: SelectXNATfolderButton
        function SelectXNATfolderButtonPushed(app, event)
            reset(app)
            defFolder = getpref('deniftify','XNATfolder','');

            if ~exist(defFolder,"dir")
                defFolder='' ;
            end

            selpath = uigetdir(defFolder,'Select XNAT folder');

            if ischar(selpath) && exist(selpath,'dir')
                setpref('deniftify','XNATfolder',selpath)
                app.XNATfolderLabel.Text = selpath ;

                statusokd = findDicom(app) ;
                statusokn = findNifti(app) ;

                figure(app.DeniftifyAppUIFigure) % bring to front

            end

        end

        % Value changed function: NumignoredendslicesEditField
        function NumignoredendslicesEditFieldValueChanged(app, event)
            value = app.NumignoredendslicesEditField.Value;
            app.slOffset = value ;
            drawnifti(app)
            drawdicom(app)
        end

        % Button pushed function: SaveasmatButton
        function SaveasmatButtonPushed(app, event)
            switch app.niftiInput
                case 'vol'
                    fn = 'vXNAT.mat' ;
                    selpath = uigetdir(app.DICOMscansfolder,['Folder for ',fn,' output']) ;
                    vXNAT = app.vntransformed ;
                    save(fullfile(selpath,fn),'vXNAT')
                case 'fic'
                    fn = 'ficXNAT.mat' ;
                    selpath = uigetdir(app.DICOMscansfolder,['Folder for ',fn,' output']) ;
                    ficXNAT = app.vntransformed ;
                    save(fullfile(selpath,fn),'ficXNAT')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeniftifyAppUIFigure = uifigure('Visible', 'off');
            app.DeniftifyAppUIFigure.Name = 'Deniftify App';
            app.NifTItransform1DropDownLabel = uilabel(app.DeniftifyAppUIFigure);
            app.NifTItransform1DropDownLabel.Text = 'NifTI transform 1';
            app.NifTItransform1DropDown = uidropdown(app.DeniftifyAppUIFigure);
            app.NifTItransform1DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.NifTItransform1DropDown.Tooltip = {'First transform applied to NIfTI data'};
            app.transform2DropDownLabel = uilabel(app.DeniftifyAppUIFigure);
            app.transform2DropDownLabel.Text = 'transform 2';
            app.transform2DropDown = uidropdown(app.DeniftifyAppUIFigure);
            app.transform2DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.transform2DropDown.Tooltip = {'Second transfrom applied to NIfTI data.'};
            app.transform3DropDownLabel = uilabel(app.DeniftifyAppUIFigure);
            app.transform3DropDownLabel.Text = 'transform 3';
            app.transform3DropDown = uidropdown(app.DeniftifyAppUIFigure);
            app.transform3DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.transform3DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.transform4DropDownLabel = uilabel(app.DeniftifyAppUIFigure);
            app.transform4DropDownLabel.Text = 'transform 4';
            app.transform4DropDown = uidropdown(app.DeniftifyAppUIFigure);
            app.transform4DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DeniftifyLabel = uilabel(app.DeniftifyAppUIFigure);
            app.DeniftifyLabel.Text = 'Deniftify';
            app.PatientFamilyNameEditFieldLabel = uilabel(app.DeniftifyAppUIFigure);
            app.PatientFamilyNameEditFieldLabel.Text = 'Patient Family Name';
            app.PatientFamilyNameEditField = uieditfield(app.DeniftifyAppUIFigure, 'text');
            app.PatientFamilyNameEditField.Editable = 'off';
            app.PatientFamilyNameEditField.Tooltip = {'Patient Family Name in DICOM (not editable)'};
            app.PatientIDEditFieldLabel = uilabel(app.DeniftifyAppUIFigure);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.DeniftifyAppUIFigure, 'text');
            app.PatientIDEditField.Editable = 'off';
            app.PatientIDEditField.Tooltip = {'Patient ID in input DICOM (not editable)'};
            app.SaveasDICOMButton = uibutton(app.DeniftifyAppUIFigure, 'push');
            app.SaveasDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasDICOMButtonPushed, true);
            app.SaveasDICOMButton.Tooltip = {'Saves transformed NIfTI as DICOM below same folder as DICOMs.'};
            app.SaveasDICOMButton.Text = 'Save as DICOM';
            app.SelectXNATfolderButton = uibutton(app.DeniftifyAppUIFigure, 'push');
            app.SelectXNATfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectXNATfolderButtonPushed, true);
            app.SelectXNATfolderButton.Tooltip = {'Select the XNAT folder for the patient'};
            app.SelectXNATfolderButton.Text = 'Select XNAT folder';
            app.VERDICTversionDropDownLabel = uilabel(app.DeniftifyAppUIFigure);
            app.VERDICTversionDropDownLabel.Text = 'VERDICT version';
            app.VERDICTversionDropDown = uidropdown(app.DeniftifyAppUIFigure);
            app.VERDICTversionDropDown.Items = {'Verdict_v1_original', 'Registration_Verdict_v1', 'Verdict_v1', 'Verdict_v2'};
            app.VERDICTversionDropDown.Tooltip = {'NIfTI file will be taken from folder with this string in name of OUTLOG output file.'};
            app.VERDICTversionDropDown.Value = 'Verdict_v1_original';
            app.SeriesNameIncludesDropDownLabel = uilabel(app.DeniftifyAppUIFigure);
            app.SeriesNameIncludesDropDownLabel.Text = 'Series Name Includes';
            app.SeriesNameIncludesDropDown = uidropdown(app.DeniftifyAppUIFigure);
            app.SeriesNameIncludesDropDown.Items = {'b3000', 'b2000', 'b1500', 'b500', 'b90'};
            app.SeriesNameIncludesDropDown.Tooltip = {'The DICOM folder name should include this value'};
            app.SeriesNameIncludesDropDown.Value = 'b3000';
            app.XNATfolderLabel = uilabel(app.DeniftifyAppUIFigure);
            app.XNATfolderLabel.Tooltip = {'Name of XNAT folder'};
            app.XNATfolderLabel.Text = 'XNAT folder';
            app.CheckFigureNumberLabel = uilabel(app.DeniftifyAppUIFigure);
            app.CheckFigureNumberLabel.Enable = 'off';
            app.CheckFigureNumberLabel.Text = 'Check Figure Number: ';
            app.SeriesNumberEditFieldLabel = uilabel(app.DeniftifyAppUIFigure);
            app.SeriesNumberEditFieldLabel.Text = 'Series Number';
            app.SeriesNumberEditField = uieditfield(app.DeniftifyAppUIFigure, 'numeric');
            app.SeriesNumberEditField.Tooltip = {'New Series Number for output. Can e edited.'};
            app.ShowDICOMcontoursCheckBox = uicheckbox(app.DeniftifyAppUIFigure);
            app.ShowDICOMcontoursCheckBox.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.ShowDICOMcontoursCheckBox.Tooltip = {'Shows contours from DICOM on one NIfTI slice'};
            app.ShowDICOMcontoursCheckBox.Text = 'Show DICOM contours';
            app.InvertNifTICheckBox = uicheckbox(app.DeniftifyAppUIFigure);
            app.InvertNifTICheckBox.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.InvertNifTICheckBox.Tooltip = {'DIsplay inverted NIfTI (1-x)'};
            app.InvertNifTICheckBox.Text = 'Invert NifTI';
            app.InvertNifTICheckBox.Value = true;
            app.FoldernameendEditFieldLabel = uilabel(app.DeniftifyAppUIFigure);
            app.FoldernameendEditFieldLabel.Text = 'Folder name end';
            app.FoldernameendEditField = uieditfield(app.DeniftifyAppUIFigure, 'text');
            app.FoldernameendEditField.Tooltip = {'Last part of output folder name after series number, e.g. 802_fIC'};
            app.FoldernameendEditField.Value = '_fIC';
            app.AddRescaleSlopetoDICOMCheckBox = uicheckbox(app.DeniftifyAppUIFigure);
            app.AddRescaleSlopetoDICOMCheckBox.Tooltip = {'Adds Rescale Slope and Rescale Intercept to DICOM (MR classic)'};
            app.AddRescaleSlopetoDICOMCheckBox.Text = 'Add Rescale Slope to DICOM';
            app.AddRescaleSlopetoDICOMCheckBox.Value = true;
            app.NumignoredendslicesEditFieldLabel = uilabel(app.DeniftifyAppUIFigure);
            app.NumignoredendslicesEditFieldLabel.Text = 'Num ignored end slices';
            app.NumignoredendslicesEditField = uieditfield(app.DeniftifyAppUIFigure, 'numeric');
            app.NumignoredendslicesEditField.Limits = [0 100];
            app.NumignoredendslicesEditField.ValueChangedFcn = createCallbackFcn(app, @NumignoredendslicesEditFieldValueChanged, true);
            app.NumignoredendslicesEditField.Value = 1;
            app.SaveasmatButton = uibutton(app.DeniftifyAppUIFigure, 'push');
            app.SaveasmatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasmatButtonPushed, true);
            app.SaveasmatButton.Text = 'Save as .mat';
            % Show the figure after all components are created
            app.DeniftifyAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deniftify

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DeniftifyAppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DeniftifyAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeniftifyAppUIFigure)
        end
    end
end