classdef dselector < matlab.apps.AppBase
    %DSELECTOR Select DICOM file(s)
    %   dselector can be called:
    %     dselector
    %     fns = getdfiles(dselector) ;
    %     fns = getdfiles(dselector, message) ;
    %     dinfo = getdinfo(dselector) ;
    %     dinfo = getdinfo(dselector, message) ;
    %   
    %   Other file information and viewing available from buttons and
    %   right-click.
    %   
    %   Copyright 2021-2022. David Atkinson

    % Properties that correspond to app components
    properties (Access = public)
        dselectorAppUIFigure          matlab.ui.Figure
        XXfileButton                  matlab.ui.control.Button
        XXExamCardButton              matlab.ui.control.Button
        InvertSelectionButton         matlab.ui.control.Button
        SelectAllButton               matlab.ui.control.Button
        MessagesTextArea              matlab.ui.control.TextArea
        MessagesTextAreaLabel         matlab.ui.control.Label
        dicominfoButton               matlab.ui.control.Button
        dicomdispButton               matlab.ui.control.Button
        UseFileButton                 matlab.ui.control.Button
        workspacevariableEditField    matlab.ui.control.EditField
        workspacevariableEditFieldLabel  matlab.ui.control.Label
        OutputButton                  matlab.ui.control.Button
        DICOMfilesListBox             matlab.ui.control.ListBox
        DICOMfilesListBoxLabel        matlab.ui.control.Label
        ImportvariableButton          matlab.ui.control.Button
        LaunchdicomBrowserButton      matlab.ui.control.Button
        ProcessButton                 matlab.ui.control.Button
        IncludesubfoldersCheckBox     matlab.ui.control.CheckBox
        RecentsListBox                matlab.ui.control.ListBox
        RecentsListBoxLabel           matlab.ui.control.Label
        FilterDICOMTypeListBox        matlab.ui.control.ListBox
        FilterDICOMTypeListBoxLabel   matlab.ui.control.Label
        ContextMenu                   matlab.ui.container.ContextMenu
        HelpMenu                      matlab.ui.container.Menu
        CloseMenu                     matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        AddFolderMenu                 matlab.ui.container.Menu
        AddFileMenu                   matlab.ui.container.Menu
        CopytoclipboardMenu           matlab.ui.container.Menu
        AddFolderfromhereMenu         matlab.ui.container.Menu
        CheckFileFolderExistsMenu     matlab.ui.container.Menu
        DeleteEntryMenu               matlab.ui.container.Menu
        ContextMenu3                  matlab.ui.container.ContextMenu
        Copy1FilenametoClipboardMenu  matlab.ui.container.Menu
        eshowframesMenu               matlab.ui.container.Menu
        TileFramesMenu                matlab.ui.container.Menu
        dstructinfoMenu               matlab.ui.container.Menu
        sviewerMenu                   matlab.ui.container.Menu
        sliceMenu                     matlab.ui.container.Menu
        sliceeffTEechoMenu            matlab.ui.container.Menu
        IRslicemodulusMenu            matlab.ui.container.Menu
        IRslicerealMenu               matlab.ui.container.Menu
        DCEslicetemporalpositionMenu  matlab.ui.container.Menu
        DWIb0Menu                     matlab.ui.container.Menu
        DWIbisoMenu                   matlab.ui.container.Menu
        DWIb0isoMenu                  matlab.ui.container.Menu
        DWIb0b1b2Menu                 matlab.ui.container.Menu
        FFEQSMsliceeffTEmagMenu       matlab.ui.container.Menu
        ADCScannercomputedMenu        matlab.ui.container.Menu
        ESCRGBSecCaptureMenu          matlab.ui.container.Menu
    end

    
    properties (Access = public)
        dinfo  %  dinfo passed to calling function
        dfiles % dfiles passed to calling function
        recents % Recent Folders and Files. Structure with
        %         fields Items and ItemsData. Saved a preference to persist
    end
    
    properties (Access = private)
        prefname % The preference name used for persistent store or recents
    end
    
    methods (Access = private)
        
        
        function setListBoxFiles(app, fns) 
           
            if iscell(fns)
                % from dicomBrowser 
                app.DICOMfilesListBox.Items = fns{1} ;
                app.DICOMfilesListBox.ItemsData = fns{1} ;
                
            else
                % From Use File
                if isdicom(fns)
                    app.DICOMfilesListBox.Items = {fns} ; % fns is 1 file here!
                    app.DICOMfilesListBox.ItemsData = {fns} ;
                else
                    app.MessagesTextArea.Value = 'Not a DICOM file';
                end
            end
            
        end
        
        function manageRecents(app, action, Item, ItemsData)
            % manageRecents manage the Recents List Box
            r = app.recents ;
            nr = length(r) ;

            switch action
                case 'Update pref'
                    setpref('dselector',app.prefname,app.recents) ;

                case 'Update Display'
                    for ir = 1:nr
                        lbItems{ir} = r(ir).Items ;
                        lbItemsData{ir} = r(ir).ItemsData ;
                    end
                    app.RecentsListBox.Items = lbItems ;
                    app.RecentsListBox.ItemsData = lbItemsData ;
                    manageRecents(app,'Update pref')


                case 'Add Folder'
                    newFolder = uigetdir ;
                    if ischar(newFolder) && exist(newFolder,'dir')
                        newItem = newFolder;
                        newItemsData = newFolder ;
                        manageRecents(app,'Add Entry', newItem, newItemsData)
                    end
                case 'Add Folder from here'
                    thisfolder = app.RecentsListBox.Value ;
                    newFolder = uigetdir(thisfolder{1},'Select folder');
                    if ischar(newFolder) && exist(newFolder,'dir')
                        newItem = newFolder;
                        newItemsData = newFolder ;
                        manageRecents(app,'Add Entry', newItem, newItemsData)
                    end
                case 'Add File'
                    [newFile, pn] = uigetfile('*') ;
                    newFile = fullfile(pn,newFile) ;
                    if exist(newFile,'file')
                        if ~isdicom(newFile)
                            app.MessagesTextArea.Value = 'DICOM files only' ;
                        else
                            newItems = newFile;
                            newItemsData = newFile ;
                            manageRecents(app,'Add Entry', newItems, newItemsData)
                        end
                    end

                case 'Delete Entry'
                    val2delete = app.RecentsListBox.Value ;
                    if numel(val2delete) ~= 1
                        app.MessagesTextArea.Value='Delete single selection only' ;
                    else
                        indx = find(strcmp(app.RecentsListBox.ItemsData,val2delete)) ;
                        if numel(indx)>1
                            app.MessagesTextArea.Value = 'Only removing 1st occurrence' ;
                        end
                        r(indx(1)) = [] ;
                        app.recents = r ;
                        manageRecents(app,'Update Display')
                    end

                case 'Add Entry'
                    newr(2:nr+1) = r ;
                    newr(1).Items = Item ;
                    newr(1).ItemsData = ItemsData ;
                    app.recents = newr ;
                    manageRecents(app,'Update Display')
                    newVal{1} = ItemsData ;
                    app.RecentsListBox.Value = newVal ; % Make new entry current
                otherwise
                    warning(['Unknown action: ', action])
            end

            
        end
    end
    
    methods (Access = public)
        
        function results = getdfiles(app, mesg)
            if nargin == 2
                app.MessagesTextArea.Value = mesg ;
            end
            app.OutputButton.Text = 'Output dfiles' ;
            app.OutputButton.Enable = 'on' ;
            app.dfiles = '';
            waitfor(app,'dfiles')
            app.OutputButton.Enable = 'off' ;

            results = app.dfiles;
        end
        
        function results = getdinfo(app, mesg)
            if nargin == 2
                app.MessagesTextArea.Value = mesg ;
            end
            app.OutputButton.Text = 'Output dinfo' ;
            app.OutputButton.Enable = 'on' ;
            app.dinfo = '' ;
            waitfor(app,'dinfo')
            app.OutputButton.Enable = 'off' ;

            results = app.dinfo ;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.prefname = 'default' ;
            
            SOP = SOPClassInfo ;
    
            for iSOP = 1:length(SOP)
                app.FilterDICOMTypeListBox.Items{iSOP} = ...
                    [SOP(iSOP).code,' ',SOP(iSOP).desc] ;
            end
            app.FilterDICOMTypeListBox.Multiselect = true ;


            defrecent.Items = pwd ;
            defrecent.ItemsData = defrecent.Items ;
            recentlist = getpref('dselector',app.prefname,defrecent) ;
            app.recents = recentlist ;
            manageRecents(app, 'Update Display')

            if ~exist('sviewer','file')
                app.sviewerMenu.Enable = false ;
            end

            if ~exist('eshow','file')
                app.eshowframesMenu.Enable = false ;
            end

            if ~exist('XXsummary','file')
                app.XXExamCardButton.Visible = false ;
            end

            if ~exist('XXread','file')
                app.XXfileButton.Visible = false ;
            end

        end

        % Button pushed function: LaunchdicomBrowserButton
        function LaunchdicomBrowserButtonPushed(app, event)
            recentFolder = app.RecentsListBox.Value ;
            if exist(recentFolder{1},'dir')
                dicomBrowser(recentFolder{1}) ;
            else
                dicomBrowser
            end
        end

        % Button pushed function: ImportvariableButton
        function ImportvariableButtonPushed(app, event)
            wsv = app.workspacevariableEditField.Value ;
            if evalin('base',['exist(''',wsv,''')'])
                sd = evalin('base',wsv) ;
                setListBoxFiles(app, sd.Filenames)
            else
                app.MessagesTextArea.Value = ['No base workspace variable: ',wsv] ;
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            delete(app)
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            switch app.OutputButton.Text 
                case 'Output dfiles'
                    app.dfiles = app.DICOMfilesListBox.Value;
                case 'Output dinfo'
                    app.dinfo = dfparse(app.DICOMfilesListBox.Value);
                otherwise
                    app.MessagesTextArea.Value = 'Unknown button text' ;
            end
        end

        % Menu selected function: AddFolderMenu
        function AddFolderMenuSelected(app, event)
            manageRecents(app,'Add Folder')
        end

        % Menu selected function: AddFileMenu
        function AddFileMenuSelected(app, event)
            manageRecents(app,'Add File')
        end

        % Menu selected function: DeleteEntryMenu
        function DeleteEntryMenuSelected(app, event)
            manageRecents(app,'Delete Entry')
        end

        % Button pushed function: UseFileButton
        function UseFileButtonPushed(app, event)
            fn = app.RecentsListBox.Value ;
            if numel(fn) ~= 1
                app.MessagesTextArea.Value = 'Expecting 1 file to be selected';
            else
            if isfile(fn{1})
                
                setListBoxFiles(app, fn{1}) 
            else
                app.MessagesTextArea.Value = 'Use File cannot be used on folders' ;
            end
            end
        end

        % Button pushed function: dicomdispButton
        function dicomdispButtonPushed(app, event)
            tfile = tempname ;
            dfile = app.DICOMfilesListBox.Value ;
            if length(dfile) ~= 1
                app.MessagesTextArea.Value = 'Select exactly 1 file' ;
            else
                diary(tfile)
                dicomdisp(dfile{1})
                diary off
                edit(tfile)
            end
        end

        % Button pushed function: dicominfoButton
        function dicominfoButtonPushed(app, event)
            tfile = tempname ;
            dfile = app.DICOMfilesListBox.Value ;
            if length(dfile) ~= 1
                app.MessagesTextArea.Value = 'Select exactly 1 file' ;
            else
                diary(tfile)
                dicominfo(dfile{1})
                diary off
                edit(tfile)
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % Get folder(s) from Recents and then files
            fns = {} ; % cell array of filenames to fill
            ifn = 0 ;
            dlb = app.RecentsListBox.Value ;

            for id = 1:numel(dlb)
                if isfolder(dlb{id})
                    if app.IncludesubfoldersCheckBox.Value == 1
                        dstruc = dir([dlb{id},filesep,'**',...
                            filesep,'*']) ;
                    else
                        dstruc = dir(dlb{id}) ;
                    end
                    for idstruc = 1:numel(dstruc)
                        if dstruc(idstruc).isdir
                            % ignore (if sub-folders, they will be in
                            % dstruc)
                        elseif dstruc(idstruc).bytes>132
                            ifn=ifn+1;
                            fns{ifn}  = fullfile(dstruc(idstruc).folder, dstruc(idstruc).name) ;
                        end
                    end
                else % List box entry was a file
                    ifn=ifn+1;
                    fns{ifn} = dlb{id} ;
                end
            end

            % file list built, now check for SOPClass and fill List Box
            %
            % Build SOPClass from SOP listbox
            SOP = SOPClassInfo ;

            attrn = {'SeriesNumber','ProtocolName', 'SeriesDescription'} ;
            ilbx = 0 ;
            lbx = {} ;

            valSOP = app.FilterDICOMTypeListBox.Value;
            k = contains(app.FilterDICOMTypeListBox.Items,valSOP) ;
            SOPClass = {SOP(k).UID} ;

            nf = numel(fns) ;
            pstr = {['Processing ',num2str(nf),' files....']} ;
            app.DICOMfilesListBox.Items = pstr ;
            app.DICOMfilesListBox.ItemsData = pstr ;
            app.DICOMfilesListBox.Value = pstr ;
            drawnow

            for ifile = 1:nf
                if ~isfile(fns{ifile})
                    continue
                end

                dinfo = dfastinfo(fns{ifile}, attrn, SOPClass) ;
                if ~isempty(dinfo)
                    lstr = '' ;

                    for iattr = 1:length(attrn)
                        if isfield(dinfo, attrn{iattr})
                            if strcmp(attrn{iattr},'SeriesNumber')
                                sopstr = '    ';
                                if isfield(dinfo,'SOPClassUID')
                                    k = strcmp({SOP.UID},dinfo.SOPClassUID);
                                    if any(k)
                                        sopstr = SOP(k).code ;
                                    else
                                        sopstr = [' ',dinfo.SOPClassUID] ;
                                    end
                                end
                                lstr = [lstr,sprintf('%04s ',dinfo.SeriesNumber),sopstr,' | '];
                            else
                                addstr = dinfo.(attrn{iattr}) ;
                                if ~contains(lstr,addstr)
                                    lstr = [lstr, addstr,' '];
                                end
                            end
                        end
                    end
                    [pn,fn, ext] = fileparts(fns{ifile}) ;
                    lstr = [lstr, ' [',fn,ext,'] '] ;

                    ilbx = ilbx + 1 ;
                    flist{ilbx} = fns{ifile} ;
                    lbx{ilbx} = lstr ;
                end
            end

            if isempty(lbx)
                app.MessagesTextArea.Value = 'No Files found/matched' ;
                pstr = {'No Files found/matched (is HD connected?)'} ;
                app.DICOMfilesListBox.Items = pstr ;
                app.DICOMfilesListBox.ItemsData = pstr ;
                app.DICOMfilesListBox.Value = pstr ;
                drawnow
            else
                [sort_lbx, idx] = sort(lbx) ;
                flist = flist(idx) ;

                app.DICOMfilesListBox.Items = sort_lbx;
                app.DICOMfilesListBox.ItemsData = flist ;
                app.MessagesTextArea.Value = '' ;
            end


        end

        % Menu selected function: CopytoclipboardMenu
        function CopytoclipboardMenuSelected(app, event)
            selected = app.RecentsListBox.Value ;
            clipboard('copy',selected{1})
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.DICOMfilesListBox.Value = app.DICOMfilesListBox.ItemsData ;
        end

        % Value changed function: DICOMfilesListBox
        function DICOMfilesListBoxValueChanged(app, event)
            dfile = app.DICOMfilesListBox.Value ;
            if length(dfile) == 1
                app.dicomdispButton.Enable = true ;
                app.dicominfoButton.Enable = true ;
                app.XXExamCardButton.Enable = true ;
                app.XXfileButton.Enable = true ;
                app.eshowframesMenu.Enable = true ;
                app.Copy1FilenametoClipboardMenu.Enable = true ;
            else
                app.dicomdispButton.Enable = false ;
                app.dicominfoButton.Enable = false ;
                app.XXExamCardButton.Enable = false ;
                app.XXfileButton.Enable = false ;
                app.eshowframesMenu.Enable = false ;
                app.Copy1FilenametoClipboardMenu.Enable = false ;
            end

        end

        % Button pushed function: InvertSelectionButton
        function InvertSelectionButtonPushed(app, event)
            curValue = app.DICOMfilesListBox.Value ;
            allValues = app.DICOMfilesListBox.ItemsData ;
            
            keep = ones(1,length(allValues));
            for icval = 1:length(curValue)
                k = find(strcmp(allValues, curValue{icval})) ;
                if any(k)
                    keep(k) = 0 ;
                end
            end
            newValues = {allValues{logical(keep)}} ;
            app.DICOMfilesListBox.Value = newValues ;
        end

        % Menu selected function: AddFolderfromhereMenu
        function AddFolderfromhereMenuSelected(app, event)
            manageRecents(app,'Add Folder from here')
        end

        % Menu selected function: Copy1FilenametoClipboardMenu
        function Copy1FilenametoClipboardMenuSelected(app, event)
            selected = app.DICOMfilesListBox.Value ;
            clipboard('copy',selected{1})
        end

        % Menu selected function: eshowframesMenu
        function eshowframesMenuSelected(app, event)
            if ~exist('eshow','file')
                app.MessagesTextArea.Value = 'eshow not on path' ;
            else
                selected = app.DICOMfilesListBox.Value ;
                V = dicomreadVolume(selected{1});
                fname = selected{1} ;
                if length(fname)>30
                    fname = ['...',fname(end-26:end)] ;
                end
                eshow(V,'Name',fname)
            end
        end

        % Menu selected function: TileFramesMenu
        function TileFramesMenuSelected(app, event)
            selected = app.DICOMfilesListBox.Value ;
            dinf = dfparse(selected) ;
            [v,m,l] = d2mat(dinf,{'frame'},'op','fp') ;
            hf = figure('Name',['FRAMES from ',selected{1}]) ;
            nf = size(v,3) ;
            t = tiledlayout(hf,'flow','TileSpacing','none','TileIndexing','columnmajor') ;
            
            for iframe= 1:nf
                ax_this = nexttile ;
                ax(iframe) = ax_this ;

                him_this = imshow(v(:,:,iframe),[],'Parent',ax_this) ;
                him_this.ContextMenu = tiledimCM ;
            end
            linkaxes(ax)
            
            % Not needed any more?
            UD.ax = ax ;
            set(hf,'UserData',UD) ;

        end

        % Menu selected function: CheckFileFolderExistsMenu
        function CheckFileFolderExistsMenuSelected(app, event)
            selected = app.RecentsListBox.Value ;
            ns = length(selected) ;
            if ns == 1
                if isfolder(selected{1})
                    app.MessagesTextArea.Value = 'Selected is a valid folder' ;
                elseif isfile(selected{1})
                    if isdicom(selected{1})
                        app.MessagesTextArea.Value = 'Selected is a valid DICOM file' ;
                    else
                        app.MessagesTextArea.Value = 'Selected is a valid file but not DICOM' ;
                    end
                else
                    app.MessagesTextArea.Value = 'Selected is NOT ON PATH (check external HD?)' ;
                end
            else
                app.MessagesTextArea.Value = 'Select exactly 1 file or folder' ;
            end
        end

        % Button pushed function: XXExamCardButton
        function XXExamCardButtonPushed(app, event)
            tfile = tempname ;
            dfile = app.DICOMfilesListBox.Value ;
            if length(dfile) ~= 1
                app.MessagesTextArea.Value = 'Select exactly 1 file' ;
            else
                diary(tfile)
                XXsummary(dfile{1})
                diary off
                edit(tfile)
            end

        end

        % Button pushed function: XXfileButton
        function XXfileButtonPushed(app, event)

            dfile = app.DICOMfilesListBox.Value ;
            if length(dfile) ~= 1
                app.MessagesTextArea.Value = 'Select exactly 1 file' ;
            else
                XXread(dfile) 
            end
        end

        % Menu selected function: dstructinfoMenu
        function dstructinfoMenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            dstructinfo(app.dinfo)
        end

        % Menu selected function: sviewerMenu
        function sviewerMenuSelected(app, event)
           
        end

        % Menu selected function: sliceMenu
        function sliceMenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice'},'op','fp') ;
            sviewer(v,m, Name=[num2str(app.dinfo(1).SeriesNumber),' ',app.dinfo(1).ProtocolName])
        end

        % Menu selected function: sliceeffTEechoMenu
        function sliceeffTEechoMenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            if isfield(app.dinfo,'EffectiveEchoTime')
                [v,m] = d2mat(app.dinfo,{'slice','effTE'},'op','fp') ;
            else
                [v,m] = d2mat(app.dinfo,{'slice','echo'},'op','fp') ;
            end
            sviewer(v,m, ...
                Name=[num2str(app.dinfo(1).SeriesNumber),' ',app.dinfo(1).ProtocolName], ...
                CLim={'T2W'})
        end

        % Menu selected function: IRslicemodulusMenu
        function IRslicemodulusMenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice','itype'},'itype',6,'op','fp') ;
            sviewer(v,m, Name=[num2str(app.dinfo(1).SeriesNumber),' Modulus ',app.dinfo(1).ProtocolName])
        end

        % Menu selected function: IRslicerealMenu
        function IRslicerealMenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice','itype'},'itype',8,'op','fp') ;
            sviewer(v,m, Name=[num2str(app.dinfo(1).SeriesNumber),' Real ',app.dinfo(1).ProtocolName])
        end

        % Menu selected function: DCEslicetemporalpositionMenu
        function DCEslicetemporalpositionMenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice','dyn'},'op','fp') ;
            sviewer(v,m, Name=[num2str(app.dinfo(1).SeriesNumber),' ',app.dinfo(1).ProtocolName])
        end

        % Menu selected function: DWIb0Menu
        function DWIb0MenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice','bv'},'bv',0,'op','fp') ;
            sviewer(v,m, Name=[num2str(app.dinfo(1).SeriesNumber),' ',app.dinfo(1).ProtocolName]) 
        end

        % Menu selected function: DWIbisoMenu
        function DWIbisoMenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice','bv','ddty'},'ddty',2,'op','fp') ;
            sviewer(v,m, Name=[num2str(app.dinfo(1).SeriesNumber),' ',app.dinfo(1).ProtocolName]) 
        end

        % Menu selected function: FFEQSMsliceeffTEmagMenu
        function FFEQSMsliceeffTEmagMenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice','effTE','itype'},'itype',29,'op','fp') ;
            sviewer(v,m, Name=[num2str(app.dinfo(1).SeriesNumber),' Modulus ',app.dinfo(1).ProtocolName])
        end

        % Menu selected function: DWIb0isoMenu
        function DWIb0isoMenuSelected(app, event)
           % THIS IS TEMPORARY DOES B>0 ONLY
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice','ddty'},'ddty',2,'op','fp') ;
            sviewer(v,m, Name=[num2str(app.dinfo(1).SeriesNumber),' ',app.dinfo(1).ProtocolName]) 
        end

        % Menu selected function: ADCScannercomputedMenu
        function ADCScannercomputedMenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice'},'op','dv') ;
            vmedian = median(v(:)) ;
            if vmedian < 10^-5
                upperADC = 2e-6;
            elseif vmedian < 10^-2
                upperADC = 2e-3 ;
            else
                upperADC = 2 ;
            end
            sviewer(v,m, ...
                Name=[num2str(app.dinfo(1).SeriesNumber),' ',app.dinfo(1).ProtocolName], ...
                d4annotation = {'ADC'}, CLim = [0 upperADC])
        end

        % Menu selected function: ESCRGBSecCaptureMenu
        function ESCRGBSecCaptureMenuSelected(app, event)
            % Enhanced MR True Color secondary capture
            ffn = app.DICOMfilesListBox.Value ;
            if length(ffn) ~= 1
                return
            end
            iminfo = dicominfo(ffn{1}) ;
            im = dicomread(iminfo) ; % [ny nx 3 nframe]
            im = permute(im,[1 2 4 3]) ; % [ny nx nframe 3]
            im = reshape(im, [size(im,[1 2]) 1 size(im,[3 4])]) ; % [ny nx 1 nframe 3]
            sviewer(im,[],isrgb=true, Name=iminfo.SeriesDescription)
        end

        % Menu selected function: DWIb0b1b2Menu
        function DWIb0b1b2MenuSelected(app, event)
            app.dinfo = dfparse(app.DICOMfilesListBox.Value) ;
            [v,m] = d2mat(app.dinfo,{'slice','bv'},'op','fp') ;
            sviewer(v,m, Name=[num2str(app.dinfo(1).SeriesNumber),' ',app.dinfo(1).ProtocolName]) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dselectorAppUIFigure = uifigure('Visible', 'off');
            app.dselectorAppUIFigure.Name = 'dselector App';
            app.FilterDICOMTypeListBoxLabel = uilabel(app.dselectorAppUIFigure);
            app.FilterDICOMTypeListBoxLabel.Tooltip = {'Restrict output to specified SOP Classes'};
            app.FilterDICOMTypeListBoxLabel.Text = 'Filter DICOM Type';
            app.FilterDICOMTypeListBox = uilistbox(app.dselectorAppUIFigure);
            app.FilterDICOMTypeListBox.Items = {'SOP Classes'};
            app.FilterDICOMTypeListBox.Tooltip = {'Select SOPClasses to include when Process is pressed'};
            app.FilterDICOMTypeListBox.Value = 'SOP Classes';
            app.RecentsListBoxLabel = uilabel(app.dselectorAppUIFigure);
            app.RecentsListBoxLabel.Text = 'Recents';
            app.RecentsListBox = uilistbox(app.dselectorAppUIFigure);
            app.RecentsListBox.Items = {'Recents'};
            app.RecentsListBox.Multiselect = 'on';
            app.RecentsListBox.Tooltip = {'Right-Click for Context Menu'};
            app.RecentsListBox.Value = {'Recents'};
            app.IncludesubfoldersCheckBox = uicheckbox(app.dselectorAppUIFigure);
            app.IncludesubfoldersCheckBox.Tooltip = {'If checked, Process will look in all sub-folders'};
            app.IncludesubfoldersCheckBox.Text = {'Include '; 'sub-folders'};
            app.ProcessButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Tooltip = {'Process highlighted files/folders. Filter by selected DICOM type. Extracts Series Number and Descriptions in List Box file list below.'};
            app.ProcessButton.Text = 'Process';
            app.LaunchdicomBrowserButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.LaunchdicomBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchdicomBrowserButtonPushed, true);
            app.LaunchdicomBrowserButton.Tooltip = {'Launches dcomBrowser using highlighted folder. Useful for Single Frame DICOMs and Volume Viewer'};
            app.LaunchdicomBrowserButton.Text = 'Launch dicomBrowser';
            app.ImportvariableButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.ImportvariableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportvariableButtonPushed, true);
            app.ImportvariableButton.Tooltip = {'Import the files specified in the above workspace vairaible'};
            app.ImportvariableButton.Text = 'Import variable';
            app.DICOMfilesListBoxLabel = uilabel(app.dselectorAppUIFigure);
            app.DICOMfilesListBoxLabel.Text = {'DICOM'; 'files'};
            app.DICOMfilesListBox = uilistbox(app.dselectorAppUIFigure);
            app.DICOMfilesListBox.Items = {'DICOM Files'};
            app.DICOMfilesListBox.Multiselect = 'on';
            app.DICOMfilesListBox.ValueChangedFcn = createCallbackFcn(app, @DICOMfilesListBoxValueChanged, true);
            app.DICOMfilesListBox.Tooltip = {'Select files for output. Right-click for Context Menu'};
            app.DICOMfilesListBox.Value = {'DICOM Files'};
            app.OutputButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Enable = 'off';
            app.OutputButton.Tooltip = {'Output when called from code'};
            app.OutputButton.Text = 'Output';
            app.workspacevariableEditFieldLabel = uilabel(app.dselectorAppUIFigure);
            app.workspacevariableEditFieldLabel.Text = 'workspace variable';
            app.workspacevariableEditField = uieditfield(app.dselectorAppUIFigure, 'text');
            app.workspacevariableEditField.Tooltip = {'Name of workspace variable saved by user from dicomBrowser'};
            app.workspacevariableEditField.Value = 'seriesDetails';
            app.UseFileButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.UseFileButton.ButtonPushedFcn = createCallbackFcn(app, @UseFileButtonPushed, true);
            app.UseFileButton.Tooltip = {'Use highlighted file.'};
            app.UseFileButton.Text = 'Use File';
            app.dicomdispButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.dicomdispButton.ButtonPushedFcn = createCallbackFcn(app, @dicomdispButtonPushed, true);
            app.dicomdispButton.Tooltip = {'Runs dicomdisp on selected file, putting output in an edtor window (for view and search only)'};
            app.dicomdispButton.Text = 'dicomdisp';
            app.dicominfoButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.dicominfoButton.ButtonPushedFcn = createCallbackFcn(app, @dicominfoButtonPushed, true);
            app.dicominfoButton.Tooltip = {'Runs dicominfo on selected file. Output goes to editor for view and search only.'};
            app.dicominfoButton.Text = 'dicominfo';
            app.MessagesTextAreaLabel = uilabel(app.dselectorAppUIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.dselectorAppUIFigure);
            app.SelectAllButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Tooltip = {'Select all files in List Box'};
            app.SelectAllButton.Text = 'Select All';
            app.InvertSelectionButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.InvertSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @InvertSelectionButtonPushed, true);
            app.InvertSelectionButton.Tooltip = {'Invert the file selection'};
            app.InvertSelectionButton.Text = 'Invert Selection';
            app.XXExamCardButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.XXExamCardButton.ButtonPushedFcn = createCallbackFcn(app, @XXExamCardButtonPushed, true);
            app.XXExamCardButton.Tooltip = {'Summary of an XX ExamCard file'};
            app.XXExamCardButton.Text = 'XX ExamCard';
            app.XXfileButton = uibutton(app.dselectorAppUIFigure, 'push');
            app.XXfileButton.ButtonPushedFcn = createCallbackFcn(app, @XXfileButtonPushed, true);
            app.XXfileButton.Tooltip = {'Outputs parametes in an XX file.'};
            app.XXfileButton.Text = 'XX file';
            app.ContextMenu = uicontextmenu(app.dselectorAppUIFigure);
            app.HelpMenu = uimenu(app.ContextMenu);
            app.HelpMenu.Text = 'Help';
            app.CloseMenu = uimenu(app.ContextMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Text = 'Close';
            % Assign app.ContextMenu
            app.dselectorAppUIFigure.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.dselectorAppUIFigure);
            app.AddFolderMenu = uimenu(app.ContextMenu2);
            app.AddFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFolderMenuSelected, true);
            app.AddFolderMenu.Text = 'Add Folder';
            app.AddFileMenu = uimenu(app.ContextMenu2);
            app.AddFileMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFileMenuSelected, true);
            app.AddFileMenu.Text = 'Add File';
            app.CopytoclipboardMenu = uimenu(app.ContextMenu2);
            app.CopytoclipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopytoclipboardMenuSelected, true);
            app.CopytoclipboardMenu.Text = 'Copy to clipboard';
            app.AddFolderfromhereMenu = uimenu(app.ContextMenu2);
            app.AddFolderfromhereMenu.MenuSelectedFcn = createCallbackFcn(app, @AddFolderfromhereMenuSelected, true);
            app.AddFolderfromhereMenu.Text = 'Add Folder from here';
            app.CheckFileFolderExistsMenu = uimenu(app.ContextMenu2);
            app.CheckFileFolderExistsMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckFileFolderExistsMenuSelected, true);
            app.CheckFileFolderExistsMenu.Text = 'Check File/Folder Exists';
            app.DeleteEntryMenu = uimenu(app.ContextMenu2);
            app.DeleteEntryMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteEntryMenuSelected, true);
            app.DeleteEntryMenu.Separator = 'on';
            app.DeleteEntryMenu.Text = 'Delete Entry';
            % Assign app.ContextMenu2
            app.RecentsListBox.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.dselectorAppUIFigure);
            app.Copy1FilenametoClipboardMenu = uimenu(app.ContextMenu3);
            app.Copy1FilenametoClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @Copy1FilenametoClipboardMenuSelected, true);
            app.Copy1FilenametoClipboardMenu.Text = 'Copy 1 Filename to Clipboard';
            app.eshowframesMenu = uimenu(app.ContextMenu3);
            app.eshowframesMenu.MenuSelectedFcn = createCallbackFcn(app, @eshowframesMenuSelected, true);
            app.eshowframesMenu.Text = 'eshow frames';
            app.TileFramesMenu = uimenu(app.ContextMenu3);
            app.TileFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @TileFramesMenuSelected, true);
            app.TileFramesMenu.Text = 'Tile Frames';
            app.dstructinfoMenu = uimenu(app.ContextMenu3);
            app.dstructinfoMenu.MenuSelectedFcn = createCallbackFcn(app, @dstructinfoMenuSelected, true);
            app.dstructinfoMenu.Tooltip = {'DICOM structure info and single image display'};
            app.dstructinfoMenu.Text = 'dstructinfo';
            app.sviewerMenu = uimenu(app.ContextMenu3);
            app.sviewerMenu.MenuSelectedFcn = createCallbackFcn(app, @sviewerMenuSelected, true);
            app.sviewerMenu.Text = 'sviewer';
            app.sliceMenu = uimenu(app.sviewerMenu);
            app.sliceMenu.MenuSelectedFcn = createCallbackFcn(app, @sliceMenuSelected, true);
            app.sliceMenu.Text = 'slice';
            app.sliceeffTEechoMenu = uimenu(app.sviewerMenu);
            app.sliceeffTEechoMenu.MenuSelectedFcn = createCallbackFcn(app, @sliceeffTEechoMenuSelected, true);
            app.sliceeffTEechoMenu.Text = 'slice, effTE / echo';
            app.IRslicemodulusMenu = uimenu(app.sviewerMenu);
            app.IRslicemodulusMenu.MenuSelectedFcn = createCallbackFcn(app, @IRslicemodulusMenuSelected, true);
            app.IRslicemodulusMenu.Text = '(IR) slice, modulus';
            app.IRslicerealMenu = uimenu(app.sviewerMenu);
            app.IRslicerealMenu.MenuSelectedFcn = createCallbackFcn(app, @IRslicerealMenuSelected, true);
            app.IRslicerealMenu.Text = '(IR) slice, real';
            app.DCEslicetemporalpositionMenu = uimenu(app.sviewerMenu);
            app.DCEslicetemporalpositionMenu.MenuSelectedFcn = createCallbackFcn(app, @DCEslicetemporalpositionMenuSelected, true);
            app.DCEslicetemporalpositionMenu.Text = '(DCE) slice, temporal position';
            app.DWIb0Menu = uimenu(app.sviewerMenu);
            app.DWIb0Menu.MenuSelectedFcn = createCallbackFcn(app, @DWIb0MenuSelected, true);
            app.DWIb0Menu.Text = '(DWI) b=0';
            app.DWIbisoMenu = uimenu(app.sviewerMenu);
            app.DWIbisoMenu.MenuSelectedFcn = createCallbackFcn(app, @DWIbisoMenuSelected, true);
            app.DWIbisoMenu.Text = '(DWI) b iso';
            app.DWIb0isoMenu = uimenu(app.sviewerMenu);
            app.DWIb0isoMenu.MenuSelectedFcn = createCallbackFcn(app, @DWIb0isoMenuSelected, true);
            app.DWIb0isoMenu.Text = '(DWI) b=[0, iso]';
            app.DWIb0b1b2Menu = uimenu(app.sviewerMenu);
            app.DWIb0b1b2Menu.MenuSelectedFcn = createCallbackFcn(app, @DWIb0b1b2MenuSelected, true);
            app.DWIb0b1b2Menu.Text = '(DWI) b=[0, b1, b2, ...]';
            app.FFEQSMsliceeffTEmagMenu = uimenu(app.sviewerMenu);
            app.FFEQSMsliceeffTEmagMenu.MenuSelectedFcn = createCallbackFcn(app, @FFEQSMsliceeffTEmagMenuSelected, true);
            app.FFEQSMsliceeffTEmagMenu.Text = '(FFE QSM) slice, effTE (mag)';
            app.ADCScannercomputedMenu = uimenu(app.sviewerMenu);
            app.ADCScannercomputedMenu.MenuSelectedFcn = createCallbackFcn(app, @ADCScannercomputedMenuSelected, true);
            app.ADCScannercomputedMenu.Text = '(ADC) Scanner computed';
            app.ESCRGBSecCaptureMenu = uimenu(app.sviewerMenu);
            app.ESCRGBSecCaptureMenu.MenuSelectedFcn = createCallbackFcn(app, @ESCRGBSecCaptureMenuSelected, true);
            app.ESCRGBSecCaptureMenu.Text = 'ESC RGB Sec. Capture';
            % Assign app.ContextMenu3
            app.DICOMfilesListBox.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.dselectorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dselector

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.dselectorAppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.dselectorAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dselectorAppUIFigure)
        end
    end
end