classdef dce < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DCEAppUIFigure           matlab.ui.Figure
        SequencePanel            matlab.ui.container.Panel
        DropDownLabel            matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        TRSpinnerLabel           matlab.ui.control.Label
        TRSpinner                matlab.ui.control.Spinner
        TESpinnerLabel           matlab.ui.control.Label
        TESpinner                matlab.ui.control.Spinner
        FASpinnerLabel           matlab.ui.control.Label
        FASpinner                matlab.ui.control.Spinner
        SpoilIncrSpinnerLabel    matlab.ui.control.Label
        SpoilIncrSpinner         matlab.ui.control.Spinner
        ShotDummiesSpinnerLabel  matlab.ui.control.Label
        ShotDummiesSpinner       matlab.ui.control.Spinner
        ShotADConSpinnerLabel    matlab.ui.control.Label
        ShotADConSpinner         matlab.ui.control.Spinner
        nPESpinnerLabel          matlab.ui.control.Label
        nPESpinner               matlab.ui.control.Spinner
        FlipSweepDropDownLabel   matlab.ui.control.Label
        FlipSweepDropDown        matlab.ui.control.DropDown
        DurationLabel            matlab.ui.control.Label
        PlotSeqButton            matlab.ui.control.Button
        TissuePanel              matlab.ui.container.Panel
        T1SpinnerLabel           matlab.ui.control.Label
        T1Spinner                matlab.ui.control.Spinner
        T2SpinnerLabel           matlab.ui.control.Label
        T2Spinner                matlab.ui.control.Spinner
        RelaxivitySpinnerLabel   matlab.ui.control.Label
        RelaxivitySpinner        matlab.ui.control.Spinner
        AIFDropDownLabel         matlab.ui.control.Label
        AIFDropDown              matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sq_store % Description
    end
    
    methods (Access = private)
        
        function buildseq(app)
            ex.TR = app.TRSpinner.Value ;
            ex.TE = app.TESpinner.Value ;
            ex.FA = app.FASpinner.Value ;
            ex.spoil_incr = app.SpoilIncrSpinner.Value ;
            ex.shotdummies = app.ShotDummiesSpinner.Value ;
            ex.shotadc = app.ShotADConSpinner.Value ;
            ex.flip_sweep = app.FlipSweepDropDown.Value ;
            ex.nomT1 = app.T1Spinner.Value ;
            ex.nomT2 = app.T2Spinner.Value ;
            
            nPE = app.nPESpinner.Value ;
            nshot = ceil(nPE / ex.shotadc) ;
            if nshot*ex.shotadc ~= nPE
                warning('nPE not a multiple of shot ADC')
            end
            
            next_tstart = 0 ;
            next_spoil_index = 1;
            sq = struct ;
            
            for ishot = 1: nshot
                [sq, next_tstart, next_spoil_index] = buildshot(app, sq, next_tstart, ...
                   next_spoil_index, ex ) ;
            end
            
            app.DurationLabel.Text = [num2str(next_tstart),'s'] ;
            
            app.sq_store = sq ;
           
            
        end
       
        
        function  [sq, next_tstart, next_spoil_index] = buildshot(app, sqin, next_tstart, ...
                next_spoil_index, ex )
            
            % from
            % function [sq, next_tstart, next_spoil_index] = build_shot(series, next_tstart, next_spoil_index)
            
            ntotal = ex.shotdummies + ex.shotadc ;
            shot_fa = qflip_sweep(ex.FA, ex.shotdummies, ntotal, ex.flip_sweep) ; % compute flip angle sweep
            
            np = next_spoil_index + (ntotal-1) ; % total number of RF spoil pulses from beginning to end of this shot
            p=[1:np];
            phi = cumsum((p-1).* ex.spoil_incr) ; % phi will be length np, just use the last ntotal for this shot
            sq = struct ;
            
            for isq = 1: ntotal
                sq(isq).crush = false ; % no crush in a shot
                sq(isq).dur = ex.TR ;
                sq(isq).T1 = ex.nomT1 ; sq(isq).T2 = ex.nomT2 ;
                sq(isq).tstart = next_tstart ;
                next_tstart = sq(isq).tstart + sq(isq).dur ;
                sq(isq).tmid = sq(isq).tstart + sq(isq).dur/2 ;
                
                sq(isq).FA = shot_fa(isq) ;
                
                sq(isq).phase = phi(np-ntotal+isq) ;
                if isq <= ex.shotdummies
                    sq(isq).ADCon = false ;
                else
                    sq(isq).ADCon = true ;
                end
            end
            
            next_spoil_index = np + 1;
            if isfield(sqin,'FA')
                sq = cat(2, sqin, sq) ;
            end
            
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            TRSpinnerValueChanged(app) ;
        end

        % Value changed function: FASpinner, FlipSweepDropDown, 
        % ShotADConSpinner, ShotDummiesSpinner, SpoilIncrSpinner, 
        % TESpinner, TRSpinner, nPESpinner
        function TRSpinnerValueChanged(app, event)
            value = app.TRSpinner.Value;
            buildseq(app) ;
            
            sq = app.sq_store ;
            
            % just one tissue for now.
            tissueAIF = app.AIFDropDown.Value ;
            r1 = app.RelaxivitySpinner.Value ;
            T10_s = app.T1Spinner.Value / 1000 ;
            
            for isq = 1:length(sq)
                if sq(isq).ADCon == true
                    t = sq(isq).tmid ;
                    conc = AIF(t/1000,tissueAIF)  ;
                    % Cs = [0 : 0.01 : 1] ; % mM concentrations.
                    Rs = 1/T10_s + r1.*conc ;
                    T1s = 1000./ Rs ; % ms

                    sq(isq).T1 = T1s;
                end
            end
            
            app.sq_store = sq ;

        end

        % Button pushed function: PlotSeqButton
        function PlotSeqButtonPushed(app, event)
            % code from build_seq
            
            sq = app.sq_store;
            
           
            f = figure('Name',['Sequence']) ;
            nsq = length(sq) ;
            
            ts = [sq.tstart] ;
            FAs = [sq.FA] ;
            fax = gca;
            % fax = f.CurrentAxes ;
            plot(fax, ts, FAs, 'b.')
            hold(fax,'on') 
            
            ADCs = [sq.ADCon] ;
            yADC = -1*ones([1 length(ADCs)]) ;
            loc = find(ADCs == false) ;
            tsa = [sq.tmid] ;
            tsa(loc) = [] ; yADC(loc) = [] ;
            
            plot(fax, tsa,yADC,'r.')
            
            tot_dur = sq(nsq).dur + sq(nsq).tstart - sq(1).tstart ;
            
            xlabel(fax, ['Time (ms).  dur: ',num2str(tot_dur)])
            ylabel(fax, 'FA (degrees)')
            
            yyaxis(fax,'right')
            plot(fax,tsa, [sq.T1], 'k.')
            ylabel(fax,'T1')
            grid(fax,'on')
            yyaxis(fax,'left')
            axis(fax,[min([ts tsa]) max([ts tsa]) -2 max(FAs)+1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DCEAppUIFigure = uifigure('Visible', 'off');
            app.DCEAppUIFigure.Name = 'DCE App';
            app.SequencePanel = uipanel(app.DCEAppUIFigure);
            app.SequencePanel.Title = 'Sequence';
            app.DropDownLabel = uilabel(app.SequencePanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.SequencePanel);
            app.TRSpinnerLabel = uilabel(app.SequencePanel);
            app.TRSpinnerLabel.Text = 'TR';
            app.TRSpinner = uispinner(app.SequencePanel);
            app.TRSpinner.ValueChangedFcn = createCallbackFcn(app, @TRSpinnerValueChanged, true);
            app.TRSpinner.Value = 10;
            app.TESpinnerLabel = uilabel(app.SequencePanel);
            app.TESpinnerLabel.Text = 'TE';
            app.TESpinner = uispinner(app.SequencePanel);
            app.TESpinner.ValueChangedFcn = createCallbackFcn(app, @TRSpinnerValueChanged, true);
            app.TESpinner.Value = 5;
            app.FASpinnerLabel = uilabel(app.SequencePanel);
            app.FASpinnerLabel.Text = 'FA';
            app.FASpinner = uispinner(app.SequencePanel);
            app.FASpinner.ValueChangedFcn = createCallbackFcn(app, @TRSpinnerValueChanged, true);
            app.FASpinner.Value = 10;
            app.SpoilIncrSpinnerLabel = uilabel(app.SequencePanel);
            app.SpoilIncrSpinnerLabel.Text = 'Spoil Incr';
            app.SpoilIncrSpinner = uispinner(app.SequencePanel);
            app.SpoilIncrSpinner.ValueChangedFcn = createCallbackFcn(app, @TRSpinnerValueChanged, true);
            app.SpoilIncrSpinner.Value = 150;
            app.ShotDummiesSpinnerLabel = uilabel(app.SequencePanel);
            app.ShotDummiesSpinnerLabel.Text = 'Shot Dummies';
            app.ShotDummiesSpinner = uispinner(app.SequencePanel);
            app.ShotDummiesSpinner.ValueChangedFcn = createCallbackFcn(app, @TRSpinnerValueChanged, true);
            app.ShotADConSpinnerLabel = uilabel(app.SequencePanel);
            app.ShotADConSpinnerLabel.Text = 'Shot ADCon';
            app.ShotADConSpinner = uispinner(app.SequencePanel);
            app.ShotADConSpinner.ValueChangedFcn = createCallbackFcn(app, @TRSpinnerValueChanged, true);
            app.ShotADConSpinner.Value = 16;
            app.nPESpinnerLabel = uilabel(app.SequencePanel);
            app.nPESpinnerLabel.Text = 'nPE';
            app.nPESpinner = uispinner(app.SequencePanel);
            app.nPESpinner.ValueChangedFcn = createCallbackFcn(app, @TRSpinnerValueChanged, true);
            app.nPESpinner.Value = 128;
            app.FlipSweepDropDownLabel = uilabel(app.SequencePanel);
            app.FlipSweepDropDownLabel.Text = 'Flip Sweep';
            app.FlipSweepDropDown = uidropdown(app.SequencePanel);
            app.FlipSweepDropDown.Items = {'None', 'quad', 'quarter'};
            app.FlipSweepDropDown.ValueChangedFcn = createCallbackFcn(app, @TRSpinnerValueChanged, true);
            app.FlipSweepDropDown.Value = 'None';
            app.DurationLabel = uilabel(app.SequencePanel);
            app.DurationLabel.Text = 'Duration';
            app.PlotSeqButton = uibutton(app.SequencePanel, 'push');
            app.PlotSeqButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSeqButtonPushed, true);
            app.PlotSeqButton.Text = 'Plot Seq';
            app.TissuePanel = uipanel(app.DCEAppUIFigure);
            app.TissuePanel.Title = 'Tissue';
            app.T1SpinnerLabel = uilabel(app.TissuePanel);
            app.T1SpinnerLabel.Text = 'T1';
            app.T1Spinner = uispinner(app.TissuePanel);
            app.T1Spinner.Value = 1400;
            app.T2SpinnerLabel = uilabel(app.TissuePanel);
            app.T2SpinnerLabel.Text = 'T2';
            app.T2Spinner = uispinner(app.TissuePanel);
            app.T2Spinner.Value = 50;
            app.RelaxivitySpinnerLabel = uilabel(app.TissuePanel);
            app.RelaxivitySpinnerLabel.Text = 'Relaxivity';
            app.RelaxivitySpinner = uispinner(app.TissuePanel);
            app.RelaxivitySpinner.Value = 4;
            app.AIFDropDownLabel = uilabel(app.TissuePanel);
            app.AIFDropDownLabel.Text = 'AIF';
            app.AIFDropDown = uidropdown(app.TissuePanel);
            app.AIFDropDown.Items = {'ParkerM', 'ParkerMearly'};
            app.AIFDropDown.Value = 'ParkerM';
            app.UIAxes = uiaxes(app.DCEAppUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.DCEAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dce

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DCEAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DCEAppUIFigure)
        end
    end
end