classdef dselect_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CloseButton                   matlab.ui.control.Button
        DicomSelectorLabel            matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        SelectFolderButton            matlab.ui.control.Button
        PreviousFoldersDropDown       matlab.ui.control.DropDown
        PreviousFoldersDropDownLabel  matlab.ui.control.Label
        ProcessButton                 matlab.ui.control.Button
        SOPListBox                    matlab.ui.control.ListBox
        SOPListBoxLabel               matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        FolderTextArea                matlab.ui.control.TextArea
        FolderTextAreaLabel           matlab.ui.control.Label
        FilesListBox                  matlab.ui.control.ListBox
        FilesListBoxLabel             matlab.ui.control.Label
        SelectFilesButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        folder % DICOM Folder name
    end
    
    properties (Access = public)
        freturn % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ispref('dselect','DropDown')
                ddin = getpref('dselect','DropDown') ;
                app.PreviousFoldersDropDown.Items = ddin ;
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.folder = pref_uigetdir('dselect', 'folder', '', 'Select Folder') ;
            if exist(app.folder,'dir')
                app.FolderTextArea.Value = app.folder ;
                app.ProcessButton.Enable = true ;
                app.SelectFolderButton.Enable = true ;
                ddi = app.PreviousFoldersDropDown.Items ;
                if isempty(ddi) || ~isequal(app.folder, ddi{1})
                    ddin = [{app.folder}, ddi(:)' ] ;
                    app.PreviousFoldersDropDown.Items = ddin ;
                    app.PreviousFoldersDropDown.Value = app.folder ;
                    setpref('dselect','DropDown',ddin) ;
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            d = dir(app.folder) ;
            nf = length(d) ;
            ilbx = 0 ;
            lbx = cell(1) ;
            
            lb1_str = {['Updating (',num2str(nf),' files) ....']} ;
            app.FilesListBox.Items = lb1_str ;
            drawnow
            opfn = true ; % output file names
            
            EMR = '1.2.840.10008.5.1.4.1.1.4.1' ;
            RAW = '1.2.840.10008.5.1.4.1.1.66' ;
            SCC = '1.2.840.10008.5.1.4.1.1.7' ;
            GPS = '1.2.840.10008.5.1.4.1.1.11.1' ;
            PDS = '1.3.46.670589.11.0.0.12.2' ; % Philips Private Data Storage (single frame DICOM)
            PEC = '1.3.46.670589.11.0.0.12.4' ; % Philips ExamCard
            SR  = '1.2.840.10008.5.1.4.1.1.88.11' ; % Structured Report (for OsiriX ROIs)
            SOPClassUIDs = {'all', EMR, RAW, SCC, GPS, PDS, PEC, SR } ;


            SOPClass = app.SOPListBox.Value ;

            if strcmp(SOPClass, SR) % Add attributes for SR
                attrn = {'SeriesNumber','ProtocolName', 'SeriesDescription','PatientName', ...
                    'StudyDate', 'StudyTime', ...
                    'ContentDate', 'ContentTime', 'ReferencedFrameNumber'} ;
            else
                attrn = {'SeriesNumber','ProtocolName'} ;
            end

            for ifile = 1:nf
                if ~d(ifile).isdir && d(ifile).bytes>132
                    dinfo = dfastinfo(fullfile(app.folder,d(ifile).name), attrn, SOPClass) ;
                    if ~isempty(dinfo)
                        lstr = '' ;

                        for iattr = 1:length(attrn)
                            if isfield(dinfo, attrn{iattr})
                                if strcmp(attrn{iattr},'SeriesNumber')
                                    sopstr = '    ';
                                    if isfield(dinfo,'SOPClassUID')
                                        switch dinfo.SOPClassUID
                                            case EMR
                                                sopstr = ' EMR' ;
                                            case RAW
                                                sopstr = ' RAW' ;
                                            case SCC
                                                sopstr = ' SCC' ;
                                            case GPS
                                                sopstr = ' GPS' ;
                                            case PDS
                                                sopstr = ' PDS' ;
                                            case PEC
                                                sopstr = ' PEC' ;
                                            case SR
                                                sopstr = ' SR';
                                            otherwise
                                                sopstr = [' ',dinfo.SOPClassUID] ;
                                        end
                                    end
                                    lstr = [lstr,sprintf('%04s',dinfo.SeriesNumber),sopstr,' | '];
                                else
                                    lstr = [lstr, dinfo.(attrn{iattr}),' '];
                                end
                            end

                        end

                        if opfn
                            lstr = [lstr, ' [',d(ifile).name,'] '] ;
                        end

                        ilbx = ilbx + 1 ;
                        flist{ilbx} = fullfile(app.folder,d(ifile).name) ;
                        lbx{ilbx} = lstr ;

                        app.SOPListBox.Items = {lbx{:},['UPDATING ... (Done ',d(ifile).name,')']} ;
                        
                        %set(handles.listbox1,'Value',ilbx+1)
                        drawnow limitrate
                    end
                end

            end

            UD = get(handles.output,'UserData') ;
            if ilbx == 0
                set(handles.listbox1,'String','NO MATCHING DICOM FILES FOUND.')
                UD.flist = [] ;
            else

                [sort_lbx, idx] = sort(lbx) ;
                flist = flist(idx) ;


                UD.flist = flist ;

                set(handles.listbox1,'String',sort_lbx)
                set(handles.listbox1,'Value', 1)
            end
            set(handles.output,'UserData', UD)
        end

        % Value changed function: PreviousFoldersDropDown
        function PreviousFoldersDropDownValueChanged(app, event)
            value = app.PreviousFoldersDropDown.Value;
            if exist(value,'dir')
                app.folder = value ;
                app.FolderTextArea.Value = app.folder ;
                app.ProcessButton.Enable = true ;
                app.SelectFolderButton.Enable = true ;
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.freturn = app.folder ;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.SelectFilesButton = uibutton(app.Panel, 'push');
            app.SelectFilesButton.Enable = 'off';
            app.SelectFilesButton.Tooltip = {'Select files to return'};
            app.SelectFilesButton.Text = 'Select Files';
            app.FilesListBoxLabel = uilabel(app.Panel);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.Panel);
            app.FilesListBox.Items = {};
            app.FilesListBox.Value = {};
            app.FolderTextAreaLabel = uilabel(app.Panel);
            app.FolderTextAreaLabel.Text = 'Folder';
            app.FolderTextArea = uitextarea(app.Panel);
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SOPListBoxLabel = uilabel(app.Panel);
            app.SOPListBoxLabel.Text = 'SOP';
            app.SOPListBox = uilistbox(app.Panel);
            app.SOPListBox.Items = {'All', 'Enhanced MR', 'Raw Data (multiframe XX)', 'Secondary Capture', 'Greyscale Presentation State', 'Philips MR Series Data (single frame XX)', 'Philips MR ExamCard (single frame XX)', 'Structured Report'};
            app.SOPListBox.ItemsData = {'all', '1.2.840.10008.5.1.4.1.1.4.1', '1.2.840.10008.5.1.4.1.1.66', '1.2.840.10008.5.1.4.1.1.7', '1.2.840.10008.5.1.4.1.1.11.1', '1.3.46.670589.11.0.0.12.2', '1.3.46.670589.11.0.0.12.4', '1.2.840.10008.5.1.4.1.1.88.11', ''};
            app.SOPListBox.Multiselect = 'on';
            app.SOPListBox.Tooltip = {'Choose DICOM types'};
            app.SOPListBox.Value = {'all'};
            app.ProcessButton = uibutton(app.Panel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Tooltip = {'Process folder to find DICOMs with these SOPs'};
            app.ProcessButton.Text = 'Process';
            app.PreviousFoldersDropDownLabel = uilabel(app.Panel);
            app.PreviousFoldersDropDownLabel.Text = 'PreviousFolders';
            app.PreviousFoldersDropDown = uidropdown(app.Panel);
            app.PreviousFoldersDropDown.Items = {};
            app.PreviousFoldersDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviousFoldersDropDownValueChanged, true);
            app.PreviousFoldersDropDown.Value = {};
            app.SelectFolderButton = uibutton(app.Panel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Enable = 'off';
            app.SelectFolderButton.Tooltip = {'Return this folder name'};
            app.SelectFolderButton.Text = 'Select Folder';
            app.DicomSelectorLabel = uilabel(app.UIFigure);
            app.DicomSelectorLabel.Text = 'Dicom Selector';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dselect_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end