classdef fapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DispmaxSpinnerLabel  matlab.ui.control.Label
        DispmaxSpinner       matlab.ui.control.Spinner
        DispminSpinnerLabel  matlab.ui.control.Label
        DispminSpinner       matlab.ui.control.Spinner
        XLimminSpinnerLabel  matlab.ui.control.Label
        XLimminSpinner       matlab.ui.control.Spinner
        XLimmaxSpinnerLabel  matlab.ui.control.Label
        XLimmaxSpinner       matlab.ui.control.Spinner
        currd3SpinnerLabel   matlab.ui.control.Label
        currd3Spinner        matlab.ui.control.Spinner
        currd4SpinnerLabel   matlab.ui.control.Label
        currd4Spinner        matlab.ui.control.Spinner
        YLimminSpinnerLabel  matlab.ui.control.Label
        YLimminSpinner       matlab.ui.control.Spinner
        YLimmaxSpinnerLabel  matlab.ui.control.Label
        YLimmaxSpinner       matlab.ui.control.Spinner
        CLimminSpinnerLabel  matlab.ui.control.Label
        CLimminSpinner       matlab.ui.control.Spinner
        CLimmaxSpinnerLabel  matlab.ui.control.Label
        CLimmaxSpinner       matlab.ui.control.Spinner
        gammaSpinnerLabel    matlab.ui.control.Label
        gammaSpinner         matlab.ui.control.Spinner
        CloseButton          matlab.ui.control.Button
        SquareROIButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        CallingUD % Description
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, UD)
            app.CallingUD = UD ;
            app.currd3Spinner.Value = UD.currd3 ;
            n3 = size(UD.data,3) ;
            if n3 == 1
                app.currd3Spinner.Visible = "off" ;
            else
                app.currd3Spinner.Limits = [1 n3] ;
            end
            
            app.currd4Spinner.Value = UD.currd4 ;
            n4 = size(UD.data,4) ;
            if n4 == 1
                app.currd4Spinner.Visible = "off" ;
            else
                app.currd4Spinner.Limits = [1 n4] ;
            end
            
            app.XLimminSpinner.Value = UD.ax.XLim(1) ;
            app.XLimmaxSpinner.Value = UD.ax.XLim(2) ;
            app.YLimminSpinner.Value = UD.ax.YLim(1) ;
            app.YLimmaxSpinner.Value = UD.ax.YLim(2) ;
            app.CLimminSpinner.Value = UD.ax.CLim(1) ;
            app.CLimmaxSpinner.Value = UD.ax.CLim(2) ;
            
            app.gammaSpinner.Value = UD.gamma ;
            app.DispmaxSpinner.Value = UD.dispmax ;
            app.DispminSpinner.Value = UD.dispmin ;
            app.DispmaxSpinner.Step = (UD.dispmax - UD.dispmin)/100 ;
            app.DispminSpinner.Step = app.DispmaxSpinner.Step ;
            
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Callback function: DispmaxSpinner, DispminSpinner, 
        % currd3Spinner, currd4Spinner
        function DispminSpinnerValueChanged(app, event)
            value = app.DispminSpinner.Value;
            UD = app.CallingUD ;
            UD.dispmax = app.DispmaxSpinner.Value ;
            UD.dispmin = app.DispminSpinner.Value ;
            UD.currd3 = app.currd3Spinner.Value ;
            UD.currd4 = app.currd4Spinner.Value ;
            
            fshow(UD)
            app.CallingUD = UD ;
        end

        % Value changed function: currd3Spinner
        function currd3SpinnerValueChanged(app, event)
            value = app.currd3Spinner.Value;
            DispminSpinnerValueChanged(app, event) 
        end

        % Button pushed function: SquareROIButton
        function SquareROIButtonPushed(app, event)
            UD = app.CallingUD ;
            roi = drawrectangle(UD.ax, 'FixedAspectRatio',1) ;
            UD.roi = roi ;
            set(UD.fig,'UserData',UD) ;
            app.CallingUD = UD ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DispmaxSpinnerLabel = uilabel(app.UIFigure);
            app.DispmaxSpinnerLabel.Text = 'Disp max';
            app.DispmaxSpinner = uispinner(app.UIFigure);
            app.DispmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @DispminSpinnerValueChanged, true);
            app.DispminSpinnerLabel = uilabel(app.UIFigure);
            app.DispminSpinnerLabel.Text = 'Disp min';
            app.DispminSpinner = uispinner(app.UIFigure);
            app.DispminSpinner.ValueChangedFcn = createCallbackFcn(app, @DispminSpinnerValueChanged, true);
            app.XLimminSpinnerLabel = uilabel(app.UIFigure);
            app.XLimminSpinnerLabel.Text = 'XLim min';
            app.XLimminSpinner = uispinner(app.UIFigure);
            app.XLimmaxSpinnerLabel = uilabel(app.UIFigure);
            app.XLimmaxSpinnerLabel.Text = 'XLim max';
            app.XLimmaxSpinner = uispinner(app.UIFigure);
            app.currd3SpinnerLabel = uilabel(app.UIFigure);
            app.currd3SpinnerLabel.Text = 'currd3';
            app.currd3Spinner = uispinner(app.UIFigure);
            app.currd3Spinner.ValueChangingFcn = createCallbackFcn(app, @DispminSpinnerValueChanged, true);
            app.currd3Spinner.Limits = [0 Inf];
            app.currd3Spinner.ValueChangedFcn = createCallbackFcn(app, @currd3SpinnerValueChanged, true);
            app.currd3Spinner.Value = 1;
            app.currd4SpinnerLabel = uilabel(app.UIFigure);
            app.currd4SpinnerLabel.Text = 'currd4';
            app.currd4Spinner = uispinner(app.UIFigure);
            app.currd4Spinner.Limits = [0 Inf];
            app.currd4Spinner.ValueChangedFcn = createCallbackFcn(app, @DispminSpinnerValueChanged, true);
            app.currd4Spinner.Value = 1;
            app.YLimminSpinnerLabel = uilabel(app.UIFigure);
            app.YLimminSpinnerLabel.Text = 'YLim min';
            app.YLimminSpinner = uispinner(app.UIFigure);
            app.YLimmaxSpinnerLabel = uilabel(app.UIFigure);
            app.YLimmaxSpinnerLabel.Text = 'YLim max';
            app.YLimmaxSpinner = uispinner(app.UIFigure);
            app.CLimminSpinnerLabel = uilabel(app.UIFigure);
            app.CLimminSpinnerLabel.Text = 'CLim min';
            app.CLimminSpinner = uispinner(app.UIFigure);
            app.CLimmaxSpinnerLabel = uilabel(app.UIFigure);
            app.CLimmaxSpinnerLabel.Text = 'CLim max';
            app.CLimmaxSpinner = uispinner(app.UIFigure);
            app.gammaSpinnerLabel = uilabel(app.UIFigure);
            app.gammaSpinnerLabel.Text = 'gamma';
            app.gammaSpinner = uispinner(app.UIFigure);
            app.gammaSpinner.Step = 0.1;
            app.gammaSpinner.Value = 1;
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SquareROIButton = uibutton(app.UIFigure, 'push');
            app.SquareROIButton.ButtonPushedFcn = createCallbackFcn(app, @SquareROIButtonPushed, true);
            app.SquareROIButton.Text = 'Square ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end